classdef NanoClusterSize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HEditFieldLabel        matlab.ui.control.Label
        HIdxEdit               matlab.ui.control.NumericEditField
        KEditFieldLabel        matlab.ui.control.Label
        KIdxEdit               matlab.ui.control.NumericEditField
        LEditFieldLabel        matlab.ui.control.Label
        LIdxEdit               matlab.ui.control.NumericEditField
        RadiusAEditFieldLabel  matlab.ui.control.Label
        radiusEdit             matlab.ui.control.NumericEditField
        PreviewButton          matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        latticeAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp % main app object
        
        % atom sites and coords
        unitCellAtomSites
        
        cellLengths
        cellAngles
        hkl
        convMat
        atomCoords
        
        radius
    end
    
    methods (Access = private)
        
        function Plot2dUnitCell(app)
            scatter(app.latticeAxes, app.atomCoords(3, :), app.atomCoords(4, :), "filled");
            initVertexSites = [0, 0, 0; 0, 0, 1; 0, 1, 0; 0, 1, 1; 1, 0, 0; 1, 0, 1; 1, 1, 0; 1, 1, 1]';
            adjVertexSites1 = [0, 0, 1; 0, 0, 0; 0, 1, 1; 0, 1, 0; 1, 0, 1; 1, 0, 0; 1, 1, 1; 1, 1, 0]';
            adjVertexSites2 = [0, 1, 0; 0, 1, 1; 0, 0, 0; 0, 0, 1; 1, 1, 0; 1, 1, 1; 1, 0, 0; 1, 0, 1]';
            adjVertexSites3 = [1, 0, 0; 1, 0, 1; 1, 1, 0; 1, 1, 1; 0, 0, 0; 0, 0, 1; 0, 1, 0; 0, 1, 1]';
            
            initCellVertexes = app.convMat * initVertexSites;
            adjCellVertexes1 = app.convMat * adjVertexSites1;
            adjCellVertexes2 = app.convMat * adjVertexSites2;
            adjCellVertexes3 = app.convMat * adjVertexSites3;
            
            hold(app.latticeAxes, 'on');
            vertexNum = size(initCellVertexes, 2);
            for vertexIdx = 1 : vertexNum
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes1(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes1(2, vertexIdx)], 'Color', 'blue');
                
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes2(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes2(2, vertexIdx)], 'Color', 'blue');
                
                line(app.latticeAxes, [initCellVertexes(1, vertexIdx), adjCellVertexes3(1, vertexIdx)],...
                    [initCellVertexes(2, vertexIdx), adjCellVertexes3(2, vertexIdx)], 'Color', 'blue');
            end
            hold(app.latticeAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cellLengths, cellAngles, unitCellAtomSites)
            app.callingApp = mainapp;
            app.cellLengths = cellLengths;
            app.cellAngles = cellAngles;
            app.unitCellAtomSites = AddEquivAtomSites(unitCellAtomSites);
            
            app.hkl = [0, 0, 1];
            app.HIdxEdit.Value = app.hkl(1);
            app.KIdxEdit.Value = app.hkl(2);
            app.LIdxEdit.Value = app.hkl(3);
            
            app.convMat = ConversionMatrix_hkl(app.cellLengths, app.cellAngles, app.hkl);
            app.atomCoords = app.unitCellAtomSites;
            app.atomCoords(3 : 5, :) = app.convMat * app.atomCoords(3 : 5, :);
            
            app.RadiusAEditFieldLabel.Text = ['Radius (', char(197), ')'];
            app.radius = 10;
            app.radiusEdit.Value = app.radius;
            
            Plot2dUnitCell(app);
        end

        % Value changed function: radiusEdit
        function radiusEditValueChanged(app, event)
            value = app.radiusEdit.Value;
            if value > 0
                app.radius = value;
            else
                msgbox('Please enter a positive value!');
                app.radiusEdit.Value = app.radius;
            end
        end

        % Value changed function: HIdxEdit
        function HIdxEditValueChanged(app, event)
            value = app.HIdxEdit.Value;
            if (any([value, app.hkl(2), app.hkl(3)])) && (mod(value, 1) == 0)
                app.hkl(1) = value;
            else
                app.HIdxEdit.Value = app.hkl(1);
                msgbox('Invalid input!');
            end
        end

        % Value changed function: KIdxEdit
        function KIdxEditValueChanged(app, event)
            value = app.KIdxEdit.Value;
            if (any([app.hkl(1), value, app.hkl(3)])) && (mod(value, 1) == 0)
                app.hkl(2) = value;
            else
                app.KIdxEdit.Value = app.hkl(2);
                msgbox('Invalid input!');
            end
        end

        % Value changed function: LIdxEdit
        function LIdxEditValueChanged(app, event)
            value = app.LIdxEdit.Value;
            if (any([app.hkl(1), app.hkl(2), value])) && (mod(value, 1) == 0)
                app.hkl(3) = value;
            else
                app.LIdxEdit.Value = app.hkl(3);
                msgbox('Invalid input!');
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.atomCoords = CreateNanoCluster_hkl(app.unitCellAtomSites,...
                app.cellLengths, app.cellAngles, app.hkl, app.radius);
            
            scatter(app.latticeAxes, app.atomCoords(3, :), app.atomCoords(4, :), 'filled');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.atomCoords = CreateNanoCluster_hkl(app.unitCellAtomSites,...
                app.cellLengths, app.cellAngles, app.hkl, app.radius);
            
            scatter(app.latticeAxes, app.atomCoords(3, :), app.atomCoords(4, :), 'filled');
            
            UpdateFromNanoClusterDialog(app.callingApp, app.radius, app.atomCoords);
            app.callingApp.NanoclusterMenu.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.NanoclusterMenu.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.HIdxEdit.ValueChangedFcn = createCallbackFcn(app, @HIdxEditValueChanged, true);
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.KIdxEdit.ValueChangedFcn = createCallbackFcn(app, @KIdxEditValueChanged, true);
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LIdxEdit = uieditfield(app.UIFigure, 'numeric');
            app.LIdxEdit.ValueChangedFcn = createCallbackFcn(app, @LIdxEditValueChanged, true);
            app.RadiusAEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusAEditFieldLabel.Text = 'Radius (A)';
            app.radiusEdit = uieditfield(app.UIFigure, 'numeric');
            app.radiusEdit.Limits = [0 Inf];
            app.radiusEdit.ValueChangedFcn = createCallbackFcn(app, @radiusEditValueChanged, true);
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.latticeAxes = uiaxes(app.UIFigure);
            title(app.latticeAxes, 'x-y view')
            xlabel(app.latticeAxes, 'X')
            ylabel(app.latticeAxes, 'Y')
            zlabel(app.latticeAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NanoClusterSize(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end