classdef NanoDiffEditEleProp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ElementDropDownLabel      matlab.ui.control.Label
        eleTypeDropDown           matlab.ui.control.DropDown
        ProportionEditFieldLabel  matlab.ui.control.Label
        elePropEdit               matlab.ui.control.NumericEditField
        okButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % main app object
        
        sortedCrysMat
        typeList
        propList
        index
    end
    
    methods (Access = private)
        
        function ExtractEleType(app, crysMat)
            atomNum = size(crysMat, 2);
            app.typeList = [];
            app.propList = [];
            
            [sortedType, sortedTypeIndices] = sort(crysMat(1, :));
            app.sortedCrysMat = crysMat(:, sortedTypeIndices);
            
            if atomNum > 1
                tmpType = app.sortedCrysMat(1, 1);
                tmpProp = app.sortedCrysMat(2, 1);
                app.typeList = [app.typeList, tmpType];
                app.propList = [app.propList, tmpProp];
                for atomIdx = 2 : atomNum
                    if app.sortedCrysMat(1, atomIdx) ~= tmpType
                        tmpType = app.sortedCrysMat(1, atomIdx);
                        tmpProp = app.sortedCrysMat(2, atomIdx);
                        app.typeList = [app.typeList, tmpType];
                        app.propList = [app.propList, tmpProp];
                    end
                end
            else
                app.typeList = app.sortedCrysMat(1, 1);
                app.propList = app.sortedCrysMat(2, 1);
            end
            
            typeNum = length(app.typeList);
            app.eleTypeDropDown.Items = {};
            for typeIdx = 1 : typeNum
                app.eleTypeDropDown.Items{typeIdx} = AtomTypeIdxToStr(app.typeList(typeIdx));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, rawCrysMat)
            % store main app object
            app.CallingApp = mainapp;
            
            ExtractEleType(app, rawCrysMat);
            
            tmpEleType = AtomTypeStrToIdx(app.eleTypeDropDown.Value);
            app.elePropEdit.Value = app.propList(app.typeList == tmpEleType);
        end

        % Value changed function: elePropEdit
        function elePropEditValueChanged(app, event)
            value = app.elePropEdit.Value;
            tmpEleType = AtomTypeStrToIdx(app.eleTypeDropDown.Value);
            app.sortedCrysMat(2, app.sortedCrysMat(1, :) == tmpEleType) = value;
            app.propList(app.typeList == tmpEleType) = value;
        end

        % Value changed function: eleTypeDropDown
        function eleTypeDropDownValueChanged(app, event)
            value = app.eleTypeDropDown.Value;
            tmpEleType = AtomTypeStrToIdx(value);
            app.elePropEdit.Value = app.propList(app.typeList == tmpEleType);
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            % Call main app's public function: updateFromElePropEditDialog()
            updateFromElePropEditDialog(app.CallingApp, app.sortedCrysMat);
            app.CallingApp.editElePropMenu.Enable = 'on';
            % delete the dialog app
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the elePropEditMenu in the main app
            app.CallingApp.editElePropMenu.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ElementDropDownLabel = uilabel(app.UIFigure);
            app.ElementDropDownLabel.Text = 'Element';
            app.eleTypeDropDown = uidropdown(app.UIFigure);
            app.eleTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @eleTypeDropDownValueChanged, true);
            app.ProportionEditFieldLabel = uilabel(app.UIFigure);
            app.ProportionEditFieldLabel.Text = 'Proportion';
            app.elePropEdit = uieditfield(app.UIFigure, 'numeric');
            app.elePropEdit.Limits = [0 1];
            app.elePropEdit.ValueChangedFcn = createCallbackFcn(app, @elePropEditValueChanged, true);
            app.okButton = uibutton(app.UIFigure, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NanoDiffEditEleProp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end